classdef DiceRollAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NumberofRollsTextArea       matlab.ui.control.TextArea
        NumberofRollsTextAreaLabel  matlab.ui.control.Label
        TotalTextArea               matlab.ui.control.TextArea
        TotalTextAreaLabel          matlab.ui.control.Label
        Die2TextArea                matlab.ui.control.TextArea
        Die2TextAreaLabel           matlab.ui.control.Label
        Die1TextArea                matlab.ui.control.TextArea
        Die1TextAreaLabel           matlab.ui.control.Label
        RollButton                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        numRolls = 0; % Description
        dieTotalHistory = zeros(11,1) % Description
        possibleDieTotal = [2:12] % Description
 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RollButton
        function RollButtonPushed(app, event)
            Die1 = randi([1 6]);
            Die2 = randi([1 6]);
            rollTotal = Die1 + Die2;
            app.numRolls = app.numRolls + 1;
            
            app.Die1TextArea.Value = num2str(Die1);
            app.Die2TextArea.Value = num2str(Die2);
            app.TotalTextArea.Value = num2str(rollTotal);
            app.NumberofRollsTextArea.Value = num2str(app.numRolls);
            
            app.dieTotalHistory(rollTotal-1) = app.dieTotalHistory(rollTotal-1) + 1;
            
            bar(app.UIAxes, app.possibleDieTotal, app.dieTotalHistory);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Roll Results')
            xlabel(app.UIAxes, 'Roll Total')
            ylabel(app.UIAxes, 'Frequency')
            zlabel(app.UIAxes, 'Z')
            app.RollButton = uibutton(app.UIFigure, 'push');
            app.RollButton.ButtonPushedFcn = createCallbackFcn(app, @RollButtonPushed, true);
            app.RollButton.Text = 'Roll';
            app.Die1TextAreaLabel = uilabel(app.UIFigure);
            app.Die1TextAreaLabel.Text = 'Die1';
            app.Die1TextArea = uitextarea(app.UIFigure);
            app.Die1TextArea.Placeholder = '#';
            app.Die2TextAreaLabel = uilabel(app.UIFigure);
            app.Die2TextAreaLabel.Text = 'Die 2';
            app.Die2TextArea = uitextarea(app.UIFigure);
            app.Die2TextArea.Placeholder = '#';
            app.TotalTextAreaLabel = uilabel(app.UIFigure);
            app.TotalTextAreaLabel.Text = 'Total';
            app.TotalTextArea = uitextarea(app.UIFigure);
            app.TotalTextArea.Placeholder = '#';
            app.NumberofRollsTextAreaLabel = uilabel(app.UIFigure);
            app.NumberofRollsTextAreaLabel.Text = 'Number of Rolls';
            app.NumberofRollsTextArea = uitextarea(app.UIFigure);
            app.NumberofRollsTextArea.Placeholder = '#';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiceRollAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end